classdef MaasHesapla < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_2                         matlab.ui.container.Panel
        SaatlikUcretEditField           matlab.ui.control.NumericEditField
        SaatlikUcretEditFieldLabel      matlab.ui.control.Label
        AylikGirilenDersSaatiEditField  matlab.ui.control.EditField
        AylikGirilenDersSaatiEditFieldLabel  matlab.ui.control.Label
        VeritabanListeleButton          matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        VeritabanndanSilButton          matlab.ui.control.Button
        Panel_4                         matlab.ui.container.Panel
        NetMaasEditField                matlab.ui.control.NumericEditField
        NetMaasEditFieldLabel           matlab.ui.control.Label
        SilButton                       matlab.ui.control.Button
        KaydetButton                    matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        RAPORLabel                      matlab.ui.control.Label
        YlTmTatillerButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        MaaHesaplaButton                matlab.ui.control.Button
        BilgilendirmeTextArea           matlab.ui.control.TextArea
        BilgilendirmeLabel              matlab.ui.control.Label
        KursBilgileriPanel              matlab.ui.container.Panel
        AylikKursSaatiEditField         matlab.ui.control.NumericEditField
        AylikKursSaatiEditFieldLabel    matlab.ui.control.Label
        KursSaatlikUcretEditField       matlab.ui.control.NumericEditField
        KursSaatlikUcretEditFieldLabel  matlab.ui.control.Label
        OkulaBalEkDersVarMKursvbButtonGroup  matlab.ui.container.ButtonGroup
        EvetButton                      matlab.ui.control.RadioButton
        HayirButton_2                   matlab.ui.control.RadioButton
        Panel                           matlab.ui.container.Panel
        SaatlikUcretTipiDropDown        matlab.ui.control.DropDown
        SaatlikUcretTipiDropDownLabel   matlab.ui.control.Label
        MaasTarihiDatePicker            matlab.ui.control.DatePicker
        MaasTarihiLabel                 matlab.ui.control.Label
        HesaplanacakAyDropDown          matlab.ui.control.DropDown
        HesaplanacakAyLabel             matlab.ui.control.Label
        SoyisimEditField                matlab.ui.control.EditField
        SoyisimLabel                    matlab.ui.control.Label
        IsimEditField                   matlab.ui.control.EditField
        IsimLabel                       matlab.ui.control.Label
        MAASHESAPLAMASISTEMILabel       matlab.ui.control.Label
    end

    properties (Access = private)
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SaatlikUcretTipiDropDown
        function SaatlikUcretTipiDropDownValueChanged(app, event)
            value = app.SaatlikUcretTipiDropDown.Value;
            if value== "Standart"
                app.Panel_2.Enable="off"
            elseif value== "Kendi Ucretini Gir"
                app.Panel_2.Enable="on"
            end
        end

        % Selection changed function: OkulaBalEkDersVarMKursvbButtonGroup
        function OkulaBalEkDersVarMKursvbButtonGroupSelectionChanged(app, event)
            selectedButton = app.OkulaBalEkDersVarMKursvbButtonGroup.SelectedObject;
            if selectedButton.Text== "Evet"
                app.KursBilgileriPanel.Enable="on"
            elseif selectedButton.Text== "Hayir"
                app.KursBilgileriPanel.Enable="off"
            end
        end

        % Value changed function: HesaplanacakAyDropDown
        function HesaplanacakAyDropDownValueChanged(app, event)
            value = app.HesaplanacakAyDropDown.Value;
            value=str2num(value);
            yil=2025; 
            basDate = datetime(yil, value, 1);
            sonDate = datetime(yil, value, eomday(yil, value))
            app.MaasTarihiDatePicker.Limits= [basDate,sonDate];
   
        end

        % Button pushed function: MaaHesaplaButton
        function MaaHesaplaButtonPushed(app, event)
            
            isim=app.IsimEditField.Value;
            soyisim=app.SoyisimEditField.Value;
            mevcut=app.UITable.Data;
            ay = app.HesaplanacakAyDropDown.Value;
            maasTarihi = app.MaasTarihiDatePicker.Value;
            aylik_ders_saati=app.AylikGirilenDersSaatiEditField.Value
            if isempty(isim) || isempty(soyisim) || isempty(ay) || isempty(maasTarihi) || isempty(aylik_ders_saati)
                msgbox('Lütfen tüm zorunlu alanları (*) doldurun!');
            else
                value=app.SaatlikUcretTipiDropDown.Value
                if value =="Standart"
                    saatlikUcret = 119
                elseif value =="Kendi Ucretini Gir"
                    saatlikUcret = app.SaatlikUcretEditField.Value
                    if isempty(saatlikUcret)
                        msgbox("Lütfen saatlik ücret alanını doldurunuz.")
                    end
                end
               
                ekDersSaat = floor(str2double(aylik_ders_saati)/2)
                toplamSaat = str2double(aylik_ders_saati) + ekDersSaat
                net = toplamSaat * saatlikUcret
                app.NetMaasEditField.Value = net;
                app.NetMaasEditField.ValueDisplayFormat = '%.2f';
    
                b=database('MaasHesapla','','');
                resmi= "SELECT * FROM MaasHesapla.dbo.ResmiTatilGunleri ORDER BY [Tarih] ";
                ara= "SELECT * FROM MaasHesapla.dbo.AraTatilGunleri" ;
                somestr="SELECT * FROM MaasHesapla.dbo.SomestrTatili";
                yaz="SELECT * FROM MaasHesapla.dbo.YazTatili";
                
                r=fetch(b,resmi);
                a=fetch(b,ara);
                s=fetch(b,somestr);
                y=fetch(b,yaz);
                data=[r;a;s;y];
    
                tatilGunleri=data{:,'Tarih'};
                tatilGunleri = datetime(tatilGunleri, 'InputFormat', 'yyyy-MM-dd');
                tatilTipi=data{:,'TatilTipi'};
                gunler=data{:,'Gun'};
                aciklama=data{:,'Aciklama'};
    
                basTarih = maasTarihi; 
                birSonrakiTarih = basTarih + calmonths(1);
    
                filtre = (tatilGunleri >= basTarih & tatilGunleri <= birSonrakiTarih);
    
                tatilGunleriFiltreli = tatilGunleri(filtre)
                if ismember(birSonrakiTarih, tatilGunleriFiltreli)
                    tatilGunleriFiltreli = [tatilGunleriFiltreli; birSonrakiTarih];
                end
                tatilTipiFiltreli = tatilTipi(filtre)
                tatilTipiFiltreli = strtrim(tatilTipiFiltreli)
                aciklamaFiltreli=aciklama(filtre)
                tumAciklama=strjoin(unique(aciklamaFiltreli), ', ');
    
                tamGunler = tatilGunleriFiltreli(strcmp(tatilTipiFiltreli, 'Tam'))
                yarimGunler = tatilGunleriFiltreli(strcmp(tatilTipiFiltreli, 'Yarım'))
                hafta_ici=["Monday","Tuesday","Wednesday","Thursday","Friday"]
                hafta_iciTurkce=["Pazartesi","Salı","Çarşamba","Perşembe","Cuma"]
                
                tamGunHaftaIci = [];
                for i = 1:length(tamGunler)
                    gunNumarasi = day(tamGunler(i))
                    gunAdi = datestr(tamGunler(i), 'dddd')
                    if ismember(gunAdi, hafta_ici)
                        tamGunHaftaIci = [tamGunHaftaIci; tamGunler(i)]
                    end
                end
                if ~isempty(tamGunHaftaIci)
                    disp('Haftaiçi Tam Gün Tatil Günleri:')
                    disp(tamGunHaftaIci)
                else
                    disp('Haftaiçi Tam Gün Tatil Günü Yok.')
                end
    
                TamGunHaftaIci_uzunluk=length(tamGunHaftaIci)
                tamGunSaat = TamGunHaftaIci_uzunluk * 6 
                tamGunEk=floor(tamGunSaat/2)
                toplam=tamGunEk+tamGunSaat
                net = (toplamSaat - toplam) * saatlikUcret
                app.NetMaasEditField.Value = net;
                app.NetMaasEditField.ValueDisplayFormat = '%.2f';
                 
                yarimGunHaftaIci=[]
                for i=1:length(yarimGunler)
                    gunNumarasi=day(yarimGunler(i))
                    gunAdi = datestr(yarimGunler(i), 'dddd')
                    if ismember(gunAdi, hafta_ici)
                        yarimGunHaftaIci = [yarimGunHaftaIci; yarimGunler(i)]
                    end
                end
                if ~isempty(yarimGunHaftaIci)
                    disp('Haftaiçi Yarım Gün Tatil Günleri:')
                    disp(yarimGunHaftaIci)
                else
                    disp('Haftaiçi Yarım Gün Tatil Günü Yok.')
                end
                
                yarimGunSaatleri = zeros(length(yarimGunHaftaIci), 1)
                for i = 1:length(yarimGunHaftaIci)
                    gunAdi = datestr(yarimGunHaftaIci(i), 'dddd'); 
                    gunNo = find(strcmp(hafta_ici, gunAdi));
                    gunAdiTurkce = hafta_iciTurkce{gunNo};
                    soru = sprintf('%s tarihinde( %s günü) kaç saat çalıştınız?', datestr(yarimGunHaftaIci(i), 'dd.mm.yyyy'),gunAdiTurkce)
                    cevap = inputdlg(soru, 'Yarım Gün Çalışma Saati')
                    if ~isempty(cevap)
                        yarimGunSaatleri(i) = str2double(cevap{1})
                    else
                        yarimGunSaatleri(i) = 0
                    end
                end
                if isempty(yarimGunSaatleri)
                    yarimGunSaatleri = 0;
                end
                yarimEkSaat=floor(yarimGunSaatleri/2)
                yarimGunSaatleri=yarimGunSaatleri+yarimEkSaat
                yarimGunUcret = yarimGunSaatleri * saatlikUcret
                net =  net + yarimGunUcret
                app.NetMaasEditField.Value = net;
                app.NetMaasEditField.ValueDisplayFormat = '%.2f';
    
                kursSaatlikUcret= app.KursSaatlikUcretEditField.Value
                kursDersSaati=app.AylikKursSaatiEditField.Value
                if isempty(kursDersSaati)|| isempty(kursSaatlikUcret)
                    msgbox("Lütfen kurs bilgilerini giriniz")
                else
                    kursMaas=kursDersSaati*kursSaatlikUcret
                    net=net+kursMaas
                    app.NetMaasEditField.Value= net;
                    app.NetMaasEditField.ValueDisplayFormat = '%.2f';
                end

                kesinti_miktari= toplam * saatlikUcret
                kesintisiz_maas=net+kesinti_miktari
                
                yeni=[mevcut; {isim},{soyisim},{ay},{net},{kesinti_miktari},{kesintisiz_maas},{tumAciklama}]
                app.UITable.Data=yeni;
            end
            
        end

        % Button pushed function: YlTmTatillerButton
        function YlTmTatillerButtonPushed(app, event)
            h=detay();
            h.UIFigure.Visible="on"
            b=database('MaasHesapla','','')
            h.UITable.ColumnName={"Tarih", "Açıklama","Tip","Gün"}
            h.UITable.RowStriping="on"
            resmi= "SELECT * FROM MaasHesapla.dbo.ResmiTatilGunleri ORDER BY [Tarih] "
            ara= "SELECT * FROM MaasHesapla.dbo.AraTatilGunleri" 
            somestr="SELECT * FROM MaasHesapla.dbo.SomestrTatili"
            yaz="SELECT * FROM MaasHesapla.dbo.YazTatili";
            r=fetch(b,resmi)
            a=fetch(b,ara)
            s=fetch(b,somestr)
            y=fetch(b,yaz)
            data=[r;a;s;y]
            h.UITable.Data =data; 
            mevcut=h.UITable.Data
            sayi=size(mevcut,1)
            satirNo=cell(sayi,1)
            for i=1:sayi
                satirNo{i}=num2str(i)
            end
            h.UITable.RowName=satirNo
            
            
        end

        % Button pushed function: KaydetButton
        function KaydetButtonPushed(app, event)
            b = database('MaasHesapla', '', '');
            tablo = app.UITable.Data;
            satir=size(tablo);
            satirSayisi=satir(:,1);
            for i = 1:satirSayisi
                isim = string(tablo{i, 1}); 
                soyisim = string(tablo{i, 2}); 
                ay = string(tablo{i, 3}); 
                net =  tablo{i, 4};
                kesinti_miktari = tablo{i, 5}; 
                kesintisiz_maas = tablo{i, 6};
                tumAciklama=string(tablo{i,7});
                kaydet=sprintf("INSERT INTO MaasHesapla.dbo.RaporTablosu (Isim, Soyisim, Ay, NetMaas, KesintiMiktari, KesintisizMaas,TatilGunleri) VALUES ('%s', '%s', '%s', %f, %f, %f,'%s')", ...
                isim, soyisim, ay, net, kesinti_miktari, kesintisiz_maas,tumAciklama);
                exec(b, kaydet);  
            end
            secim="SELECT * FROM MaasHesapla.dbo.RaporTablosu";
            d=fetch(b,secim)
            disp(d)
            msgbox("Veriler başarıyla veritabanına eklendi.")
        end

        % Button pushed function: SilButton
        function SilButtonPushed(app, event)
            data=app.UITable.Data
            sec=app.UITable.Selection
            if isempty(sec)
                msgbox('Seçilen bir öge yok!');
            else
                secilenSatir = unique(sec(:, 1));
                data(secilenSatir, :) = [];
                app.UITable.Data = data;   
                msgbox("Öge başarıyla silindi.")
            end
            
        end

        % Button pushed function: VeritabanndanSilButton
        function VeritabanndanSilButtonPushed(app, event)
            b = database('MaasHesapla', '', '');
            isim=app.IsimEditField.Value;
            soyisim=app.SoyisimEditField.Value;
            ay=app.HesaplanacakAyDropDown.Value;
            if isempty(isim) || isempty(soyisim) || isempty(ay)
                msgbox("Lütfen silinmesini istediğiniz satır(lar)ın isim, soyisim ve ay bilgilerini giriniz.")
           
            else
                sil=sprintf("DELETE FROM MaasHesapla.dbo.RaporTablosu WHERE Isim='%s' AND Soyisim = '%s' AND Ay = '%s'", isim, soyisim, ay);
                execute(b,sil);
                secim="SELECT * FROM MaasHesapla.dbo.RaporTablosu"
                data=fetch(b,secim)
                disp(data)
                msgbox("Girdiğiniz bilgilere ait ögeler veritabanından başarıyla silindi.")
                app.UITable.Data=data
            end
        end

        % Button pushed function: VeritabanListeleButton
        function VeritabanListeleButtonPushed(app, event)
            b = database('MaasHesapla', '', '');
            data = app.UITable.Data;
            secim= "SELECT * FROM MaasHesapla.dbo.RaporTablosu";
            k=fetch(b,secim);
            app.UITable.Data=k;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MAASHESAPLAMASISTEMILabel = uilabel(app.UIFigure);
            app.MAASHESAPLAMASISTEMILabel.Text = 'MAAS HESAPLAMA SISTEMI';
            app.Panel = uipanel(app.UIFigure);
            app.IsimLabel = uilabel(app.Panel);
            app.IsimLabel.Text = '*Isim:';
            app.IsimEditField = uieditfield(app.Panel, 'text');
            app.SoyisimLabel = uilabel(app.Panel);
            app.SoyisimLabel.Text = '*Soyisim:';
            app.SoyisimEditField = uieditfield(app.Panel, 'text');
            app.HesaplanacakAyLabel = uilabel(app.Panel);
            app.HesaplanacakAyLabel.Text = '*Hesaplanacak Ay:';
            app.HesaplanacakAyDropDown = uidropdown(app.Panel);
            app.HesaplanacakAyDropDown.Items = {'1', '2', '3', '4', '5', '6', '9', '10', '11', '12'};
            app.HesaplanacakAyDropDown.ValueChangedFcn = createCallbackFcn(app, @HesaplanacakAyDropDownValueChanged, true);
            app.HesaplanacakAyDropDown.Value = '1';
            app.MaasTarihiLabel = uilabel(app.Panel);
            app.MaasTarihiLabel.Text = '*Maas Tarihi:';
            app.MaasTarihiDatePicker = uidatepicker(app.Panel);
            app.SaatlikUcretTipiDropDownLabel = uilabel(app.Panel);
            app.SaatlikUcretTipiDropDownLabel.Text = 'Saatlik Ucret Tipi:';
            app.SaatlikUcretTipiDropDown = uidropdown(app.Panel);
            app.SaatlikUcretTipiDropDown.Items = {'Standart', 'Kendi Ucretini Gir'};
            app.SaatlikUcretTipiDropDown.ValueChangedFcn = createCallbackFcn(app, @SaatlikUcretTipiDropDownValueChanged, true);
            app.SaatlikUcretTipiDropDown.Value = 'Standart';
            app.OkulaBalEkDersVarMKursvbButtonGroup = uibuttongroup(app.UIFigure);
            app.OkulaBalEkDersVarMKursvbButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OkulaBalEkDersVarMKursvbButtonGroupSelectionChanged, true);
            app.OkulaBalEkDersVarMKursvbButtonGroup.Title = 'Okula Bağlı Ek Ders Var Mı? (Kurs vb.)';
            app.HayirButton_2 = uiradiobutton(app.OkulaBalEkDersVarMKursvbButtonGroup);
            app.HayirButton_2.Text = 'Hayir';
            app.HayirButton_2.Value = true;
            app.EvetButton = uiradiobutton(app.OkulaBalEkDersVarMKursvbButtonGroup);
            app.EvetButton.Text = 'Evet';
            app.KursBilgileriPanel = uipanel(app.UIFigure);
            app.KursBilgileriPanel.Enable = 'off';
            app.KursBilgileriPanel.Title = 'Kurs Bilgileri';
            app.KursSaatlikUcretEditFieldLabel = uilabel(app.KursBilgileriPanel);
            app.KursSaatlikUcretEditFieldLabel.Text = '*Kurs Saatlik Ucret:';
            app.KursSaatlikUcretEditField = uieditfield(app.KursBilgileriPanel, 'numeric');
            app.AylikKursSaatiEditFieldLabel = uilabel(app.KursBilgileriPanel);
            app.AylikKursSaatiEditFieldLabel.Text = '*Aylik Kurs Saati:';
            app.AylikKursSaatiEditField = uieditfield(app.KursBilgileriPanel, 'numeric');
            app.BilgilendirmeLabel = uilabel(app.UIFigure);
            app.BilgilendirmeLabel.Text = '  Bilgilendirme!  ';
            app.BilgilendirmeTextArea = uitextarea(app.UIFigure);
            app.BilgilendirmeTextArea.Editable = 'off';
            app.BilgilendirmeTextArea.Value = {'Bu sistem ücretli öğretmenler için geliştirildiğinden dolayı haftada kaç saat derse girildiğini sistem tahmin edemez. Çünkü standart ders saati her öğretmen için aynı değildir. Bu yüzden ayda kaç saat derse girdiğinizi resmi tatilleri hiç hesaba katmayarak giriniz. Resmi tatilleri sistem otomatik olarak maaştan düşecektir. Lütfen (rapor,özel izinler vb. hariç)her ay düzenli olarak girdiğiniz ders saatini yazınız.'};
            app.MaaHesaplaButton = uibutton(app.UIFigure, 'push');
            app.MaaHesaplaButton.ButtonPushedFcn = createCallbackFcn(app, @MaaHesaplaButtonPushed, true);
            app.MaaHesaplaButton.Text = 'Maaş Hesapla';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'İsim'; 'Soyisim'; 'Ay'; 'Net Maaş'; 'Kesinti Miktarı'; 'Kesintisiz Maaş'; 'Tatil Günleri'};
            app.UITable.RowName = {};
            app.YlTmTatillerButton = uibutton(app.UIFigure, 'push');
            app.YlTmTatillerButton.ButtonPushedFcn = createCallbackFcn(app, @YlTmTatillerButtonPushed, true);
            app.YlTmTatillerButton.Text = '2025 Yılı Tüm Tatiller';
            app.RAPORLabel = uilabel(app.UIFigure);
            app.RAPORLabel.Text = 'RAPOR';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'maas.jpg');
            app.KaydetButton = uibutton(app.UIFigure, 'push');
            app.KaydetButton.ButtonPushedFcn = createCallbackFcn(app, @KaydetButtonPushed, true);
            app.KaydetButton.Text = 'Kaydet';
            app.SilButton = uibutton(app.UIFigure, 'push');
            app.SilButton.ButtonPushedFcn = createCallbackFcn(app, @SilButtonPushed, true);
            app.SilButton.Text = 'Sil';
            app.Panel_4 = uipanel(app.UIFigure);
            app.NetMaasEditFieldLabel = uilabel(app.Panel_4);
            app.NetMaasEditFieldLabel.Text = 'Net Maas:';
            app.NetMaasEditField = uieditfield(app.Panel_4, 'numeric');
            app.VeritabanndanSilButton = uibutton(app.UIFigure, 'push');
            app.VeritabanndanSilButton.ButtonPushedFcn = createCallbackFcn(app, @VeritabanndanSilButtonPushed, true);
            app.VeritabanndanSilButton.Text = 'Veritabanından Sil';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'maas1.jpg');
            app.VeritabanListeleButton = uibutton(app.UIFigure, 'push');
            app.VeritabanListeleButton.ButtonPushedFcn = createCallbackFcn(app, @VeritabanListeleButtonPushed, true);
            app.VeritabanListeleButton.Text = 'Veritabanı Listele';
            app.AylikGirilenDersSaatiEditFieldLabel = uilabel(app.UIFigure);
            app.AylikGirilenDersSaatiEditFieldLabel.Text = '*Aylik Girilen Ders Saati:';
            app.AylikGirilenDersSaatiEditField = uieditfield(app.UIFigure, 'text');
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Enable = 'off';
            app.SaatlikUcretEditFieldLabel = uilabel(app.Panel_2);
            app.SaatlikUcretEditFieldLabel.Text = '*Saatlik Ucret:';
            app.SaatlikUcretEditField = uieditfield(app.Panel_2, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MaasHesapla

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end